classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        NovaredeTab                matlab.ui.container.Tab
        testLabel                  matlab.ui.control.Label
        valLabel                   matlab.ui.control.Label
        TrainLabel                 matlab.ui.control.Label
        FuncDivDropDown            matlab.ui.control.DropDown
        FunodedivisoDropDownLabel  matlab.ui.control.Label
        TestEditField              matlab.ui.control.NumericEditField
        ValEditField               matlab.ui.control.NumericEditField
        TrainEditField             matlab.ui.control.NumericEditField
        ResultadosTextArea         matlab.ui.control.TextArea
        ResultadosTextAreaLabel    matlab.ui.control.Label
        PastasButtonGroup          matlab.ui.container.ButtonGroup
        trainButton                matlab.ui.control.RadioButton
        testButton                 matlab.ui.control.RadioButton
        startButton                matlab.ui.control.RadioButton
        GravarredeButton           matlab.ui.control.Button
        FunodetreinoDropDown       matlab.ui.control.DropDown
        FunodetreinoDropDownLabel  matlab.ui.control.Label
        FunodeativaoDropDown       matlab.ui.control.DropDown
        FunodeativaoDropDownLabel  matlab.ui.control.Label
        TopologiaEditField         matlab.ui.control.EditField
        TopologiaEditFieldLabel    matlab.ui.control.Label
        TreinarredeButton          matlab.ui.control.Button
        TrainingPerformanceAxes    matlab.ui.control.UIAxes
        CarregarumaexistenteTab    matlab.ui.container.Tab
        AplicarButton              matlab.ui.control.Button
        ResultadosTextArea_2       matlab.ui.control.TextArea
        ResultadosTextArea_2Label  matlab.ui.control.Label
        datasetfolderEditField     matlab.ui.control.EditField
        DatasetEditFieldLabel      matlab.ui.control.Label
        EscolherdatasetButton      matlab.ui.control.Button
        TabGroup2                  matlab.ui.container.TabGroup
        DesenharImagemTab          matlab.ui.container.Tab
        saveButton                 matlab.ui.control.Button
        ClearButton                matlab.ui.control.Button
        UIAxesDesenho              matlab.ui.control.UIAxes
        SelecionarimagemTab        matlab.ui.container.Tab
        UIimage                    matlab.ui.control.Image
        EscolherimagemButton       matlab.ui.control.Button
        RedeEditField              matlab.ui.control.EditField
        RedeEditFieldLabel         matlab.ui.control.Label
        CarregarredeButton         matlab.ui.control.Button
        TrainingPerformanceAxes_2  matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        net = [];
        loadedNet = [];
        tr = struct();
        nNetwork = 0;
        dimensions = [20 20]

        IsDrawing = false;  % Flag to track drawing state
        LastPoint = [];     % Stores last mouse position
        DrawingData = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: UIAxesDesenho
        function UIAxesDesenhoButtonDown(app, event)
            grayImageTmp = ones(224,224);

            h = drawfreehand(app.UIAxesDesenho);
            %h.FaceAlpha = 1;
            %h.FaceSelectable = false;
            %h.LineWidth = 5;

            % Get the xy coordinates of where they drew.
            xy = h.Position;

            % get rid of imfreehand remnant.
            delete(h);
            hold(app.UIAxes, 'on');
            xCoordinates = xy(:, 1);
            yCoordinates = xy(:, 2);
            plot(app.UIAxes, xCoordinates, yCoordinates, 'k-', 'LineWidth', 28);

            promptMessage = sprintf('Guardar imagem?');
            titleBarCaption = 'Continuar?';
            button = questdlg(promptMessage, titleBarCaption, 'Ok', 'Cancelar','Ok');
            if strcmpi(button, 'Cancelar')
                cla(app.UIAxes);
                hold(app.UIAxes, 'off');
                title(app.UIAxes, "Clique Esquerdo e Arrastar para desenhar");
                return;
            end

            title(app.UIAxes, "");

            for k = 1 : length(xCoordinates)
                row = int32(yCoordinates(k));
                column = int32(xCoordinates(k));
                disp(column);
                disp(row);
                grayImageTmp(row, column) = 0;
            end

            
            %imshow(app.grayImage, 'Parent', app.UIAxes);

            % No fim limpar a janela para desenhar denovo
            cla(app.UIAxes);
            hold(app.UIAxes, 'off');
            title(app.UIAxes, "Clique Esquerdo e Arrastar para desenhar");
        end

        % Button pushed function: EscolherimagemButton
        function EscolherimagemButtonPushed(app, event)
        [filename, pathname] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files'});
        if filename == 0
            return; 
        end
        
        img = imread(fullfile(pathname, filename));
        app.UIimage.ImageSource = img;
        
        
        end

        % Button pushed function: TreinarredeButton
        function TreinarredeButtonPushed(app, event)
            % Initialize variables
            if isempty(app.TopologiaEditField.Value)
                topologia = 10;
            else
                topologia = str2double(app.TopologiaEditField.Value);
            end
            disp(str2double(app.TopologiaEditField.Value));
            funcAct = app.FunodeativaoDropDown.Value;
            funcTreino = app.FunodetreinoDropDown.Value;
            funcDiv = app.FuncDivDropDown.Value;
            trainDiv = app.TrainEditField.Value;
            valDiv = app.ValEditField.Value;
            testDiv = app.TestEditField.Value;
            % if (trainDiv + valDiv + testDiv) == 1
            %     funcDiv = 0.70;
            %     trainDiv = 0.15;
            %     valDiv = 0.15;
            %     app.FuncDivDropDown.Value = 0.70;
            %     app.TrainEditField.Value = 0.15;
            %     app.ValEditField.Value = 0.15;
            % end
            pasta_train = app.PastasButtonGroup.SelectedObject.Text;
            
            current_dir = pwd;
            pastaff = fullfile(current_dir, pasta_train);
            
            classes = {'circle', 'kite', 'parallelogram', 'square', 'trapezoid', 'triangle'};  % Nomes exatos das pastas
            num_classes = length(classes);
            imagens_por_classe = 5;

            inputs = [];
            targets = [];
     
      
            for i = 1:length(classes)
                pasta_classe = fullfile(pastaff, classes{i});
                
                if ~exist(pasta_classe, 'dir')
                    app.ResultadosTextArea.Value= 'AVISO: A pasta %s não existe!\n';
                    continue;
                end
               
                arquivos = dir(fullfile(pasta_classe, '*.png'));
       
                for j = 1:min(imagens_por_classe, length(arquivos))
                    % Carregar imagem
                    nome_arquivo = fullfile(pasta_classe, arquivos(j).name);
                                
                    try
                        img = imread(nome_arquivo);
                     
                        % Converter para binário e redimensionar
                        img_bin = imbinarize(img);
                        img_resized = imresize(img_bin, app.dimensions);
                   
                        
                        % Converter para vetor
                        inputs = [inputs, double(img_resized(:))];
                        
                        % Criar vetor de saída one-hot
                        y = zeros(length(classes), 1);
                        y(i) = 1;
                        targets = [targets, y];
                       
                    catch e
                         app.ResultadosTextArea.Value = 'ERRO ao processar arquivo ';
                        continue;
                    end
                end
            end
            app.ResultadosTextArea.Value ='Dataset processado\n';
            disp(length(inputs))
            if isempty(inputs)
                error('Nenhuma imagem foi carregada. Verifique se o caminho está correto e se existem imagens nas pastas.');
            end
            
            % Create and train network
            try
                app.net = feedforwardnet(topologia);
                app.net.layers{1}.transferFcn = funcAct;
                app.net.trainFcn = funcTreino;
               
                app.net.divideFcn = funcDiv;
                app.net.divideParam.trainRatio = trainDiv;
                app.net.divideParam.valRatio = valDiv;
                app.net.divideParam.testRatio = testDiv;
                
                %Train network
                [app.net, app.tr] = train(app.net, inputs, targets);
                
             
                app.GravarredeButton.Enable = "on";
               
                cla(app.TrainingPerformanceAxes);

                hold(app.TrainingPerformanceAxes, 'on');
                plot(app.TrainingPerformanceAxes, app.tr.epoch, app.tr.perf, 'b', 'LineWidth', 2, 'DisplayName', 'Training');
                plot(app.TrainingPerformanceAxes, app.tr.epoch, app.tr.vperf, 'g', 'LineWidth', 2, 'DisplayName', 'Validation');
                plot(app.TrainingPerformanceAxes, app.tr.epoch, app.tr.tperf, 'r', 'LineWidth', 2, 'DisplayName', 'Test');
                hold(app.TrainingPerformanceAxes, 'off');

                [min_val, min_idx] = min(app.tr.vperf);
                line(app.TrainingPerformanceAxes, app.tr.epoch(min_idx), min_val, ...
                    'Marker', 'o', 'MarkerSize', 10, 'Color', 'k', 'DisplayName', 'Best Validation');
                out = sim(app.net, inputs);
                erro = perform(app.net, out, targets);
                [~, predicted] = max(out);
                [~, expected] = max(targets);
                accuracy = mean(predicted == expected) * 100;
                app.ResultadosTextArea.Value = sprintf(...
        'Rede neuronal treinada com sucesso! Precisão global de: %.2f%% e um erro de: %.4f', ...
        accuracy, erro);
                
            catch e
                uialert(app.UIFigure, sprintf('Training failed: %s', e.message), 'Error');
            end
            app.GravarredeButton.Enable="on";
        end

        % Button pushed function: CarregarredeButton
        function CarregarredeButtonPushed(app, event)
            [file, path] = uigetfile({'*.mat;*.onnx', 'Neural Network Files (*.mat, *.onnx)'; ...
                              '*.mat', 'MAT-files (*.mat)'; ...
                              '*.onnx', 'ONNX Files (*.onnx)'}, ...
                             'Select Neural Network File');
            loadedContent = load([path, file]);
            fieldNames = fieldnames(loadedContent);
            app.loadedNet = loadedContent.(fieldNames{1});
            app.RedeEditField.Value = file;
            app.EscolherdatasetButton.Enable = "on";
            app.DatasetEditFieldLabel.Enable = "on";
            app.datasetfolderEditField.Enable = "on";
        end

        % Value changed function: TopologiaEditField
        function TopologiaEditFieldValueChanged(app, event)
            value = app.TopologiaEditField.Value;
            app.trainButton.Enable = "on";
        end

        % Button pushed function: GravarredeButton
        function GravarredeButtonPushed(app, event)
            current_dir = 'resultados_app/';
            name = sprintf('trained_network_%d.mat', app.nNetwork);
            file_path = fullfile(current_dir, name);  
            
          
            while exist(file_path, 'file')
                app.nNetwork = app.nNetwork + 1;
                name = sprintf('trained_network_%d.mat', app.nNetwork);
                file_path = fullfile(current_dir, name);
                 
                if app.nNetwork > 1000
                    error('Reached maximum network number (1000)');
                end
            end
            if ~isfolder(current_dir)
                mkdir(current_dir);
            end
            localNet = app.net;
            localTr = app.tr;
            save(file_path, 'localNet', 'localTr');  
            app.ResultadosTextArea.Value ='Rede neuronal guardada com sucesso!\n';
        end

        % Button pushed function: EscolherdatasetButton
        function EscolherdatasetButtonPushed2(app, event)
            selectedFolder = uigetdir('', 'Select Folder');
    
            % Check if user selected a folder
            if selectedFolder == 0
                % User clicked cancel
                app.datasetfolderEditField.Value = 'No folder selected';
            else
                % Display the selected folder path
                app.datasetfolderEditField.Value = selectedFolder;
                app.AplicarButton.Enable ="on";
            end
        end

        % Button pushed function: AplicarButton
        function AplicarButtonPushed(app, event)
            path_dataset = app.datasetfolderEditField.Value;
            contents = dir(path_dataset);
            isValidFolder = [contents.isdir] & ~ismember({contents.name}, {'.', '..'});
            folderNames = {contents(isValidFolder).name};
            numFolders = sum(isValidFolder);
            targets = [];
            inputs = [];
            for i = 1:numFolders
                pasta_classe = fullfile(path_dataset, folderNames{i});
                
                if ~exist(pasta_classe, 'dir')
                    app.ResultadosTextArea.Value= 'AVISO: A pasta %s não existe!\n';
                    continue;
                end
               
                ficheiros = dir(fullfile(pasta_classe, '*.png'));
       
                for j = 1:length(ficheiros)
                    % Carregar imagem
                    nome_arquivo = fullfile(pasta_classe, ficheiros(j).name);
                                
                    try
                        img = imread(nome_arquivo);
                     
                        % Converter para binário e redimensionar
                        img_bin = imbinarize(img);
                        img_resized = imresize(img_bin, app.dimensions);
                   
                        
                        % Converter para vetor
                        inputs = [inputs, double(img_resized(:))];
                        
                        % Criar vetor de saída one-hot
                        y = zeros(length(classes), 1);
                        y(i) = 1;
                        targets = [targets, y];
                       
                    catch e
                         app.ResultadosTextArea.Value = 'ERRO ao processar ficheiro ';
                        continue;
                    end
                end
            end
            out = sim(app.loadedNet, inputs);
            %plotconfusion(inputs, out);
            erro = perform(app.loadedNet, out, targets);
            [~, predicted] = max(out);
            [~, expected] = max(targets);
            accuracy = mean(predicted == expected) * 100;
            app.ResultadosTextArea_2.Value = sprintf(...
        'Rede neuronal treinada com sucesso! Precisão global de: %.2f%% e um erro de: %.4f', ...
        accuracy, erro);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NovaredeTab = uitab(app.TabGroup);
            app.NovaredeTab.Title = 'Nova rede';
            app.TrainingPerformanceAxes = uiaxes(app.NovaredeTab);
            title(app.TrainingPerformanceAxes, 'Grafico de performance')
            xlabel(app.TrainingPerformanceAxes, 'Epoch')
            ylabel(app.TrainingPerformanceAxes, 'Error')
            zlabel(app.TrainingPerformanceAxes, 'Z')
            app.TreinarredeButton = uibutton(app.NovaredeTab, 'push');
            app.TreinarredeButton.ButtonPushedFcn = createCallbackFcn(app, @TreinarredeButtonPushed, true);
            app.TreinarredeButton.Text = 'Treinar rede';
            app.TopologiaEditFieldLabel = uilabel(app.NovaredeTab);
            app.TopologiaEditFieldLabel.Text = 'Topologia';
            app.TopologiaEditField = uieditfield(app.NovaredeTab, 'text');
            app.TopologiaEditField.ValueChangedFcn = createCallbackFcn(app, @TopologiaEditFieldValueChanged, true);
            app.TopologiaEditField.Placeholder = '[format]';
            app.FunodeativaoDropDownLabel = uilabel(app.NovaredeTab);
            app.FunodeativaoDropDownLabel.Text = 'Função de ativação';
            app.FunodeativaoDropDown = uidropdown(app.NovaredeTab);
            app.FunodeativaoDropDown.Items = {'tansig', 'logsig', 'purelm'};
            app.FunodeativaoDropDown.Value = 'tansig';
            app.FunodetreinoDropDownLabel = uilabel(app.NovaredeTab);
            app.FunodetreinoDropDownLabel.Text = 'Função de treino';
            app.FunodetreinoDropDown = uidropdown(app.NovaredeTab);
            app.FunodetreinoDropDown.Items = {'trainlm', 'trainbfg', 'trainrp', 'trainscg', 'traincgb', 'traincgf', 'traincgp', 'trainoss', 'traingdx'};
            app.FunodetreinoDropDown.Value = 'trainlm';
            app.GravarredeButton = uibutton(app.NovaredeTab, 'push');
            app.GravarredeButton.ButtonPushedFcn = createCallbackFcn(app, @GravarredeButtonPushed, true);
            app.GravarredeButton.Enable = 'off';
            app.GravarredeButton.Text = 'Gravar rede';
            app.PastasButtonGroup = uibuttongroup(app.NovaredeTab);
            app.PastasButtonGroup.Title = 'Pastas';
            app.startButton = uiradiobutton(app.PastasButtonGroup);
            app.startButton.Text = 'start';
            app.startButton.Value = true;
            app.testButton = uiradiobutton(app.PastasButtonGroup);
            app.testButton.Text = 'test';
            app.trainButton = uiradiobutton(app.PastasButtonGroup);
            app.trainButton.Text = 'train';
            app.ResultadosTextAreaLabel = uilabel(app.NovaredeTab);
            app.ResultadosTextAreaLabel.Text = 'Resultados';
            app.ResultadosTextArea = uitextarea(app.NovaredeTab);
            app.ResultadosTextArea.Editable = 'off';
            app.TrainEditField = uieditfield(app.NovaredeTab, 'numeric');
            app.TrainEditField.Limits = [0 1];
            app.TrainEditField.Value = 0.7;
            app.ValEditField = uieditfield(app.NovaredeTab, 'numeric');
            app.ValEditField.Limits = [0 1];
            app.ValEditField.Value = 0.15;
            app.TestEditField = uieditfield(app.NovaredeTab, 'numeric');
            app.TestEditField.Limits = [0 1];
            app.TestEditField.Value = 0.15;
            app.FunodedivisoDropDownLabel = uilabel(app.NovaredeTab);
            app.FunodedivisoDropDownLabel.Text = 'Função de divisão';
            app.FuncDivDropDown = uidropdown(app.NovaredeTab);
            app.FuncDivDropDown.Items = {'dividerand', 'divideblock', 'divideint', 'divideind'};
            app.FuncDivDropDown.Value = 'dividerand';
            app.TrainLabel = uilabel(app.NovaredeTab);
            app.TrainLabel.Text = 'Train';
            app.valLabel = uilabel(app.NovaredeTab);
            app.valLabel.Text = 'val';
            app.testLabel = uilabel(app.NovaredeTab);
            app.testLabel.Text = 'test';
            app.CarregarumaexistenteTab = uitab(app.TabGroup);
            app.CarregarumaexistenteTab.Title = 'Carregar uma existente';
            app.TrainingPerformanceAxes_2 = uiaxes(app.CarregarumaexistenteTab);
            title(app.TrainingPerformanceAxes_2, 'Grafico de performance')
            xlabel(app.TrainingPerformanceAxes_2, 'Epoch')
            ylabel(app.TrainingPerformanceAxes_2, 'Error')
            zlabel(app.TrainingPerformanceAxes_2, 'Z')
            app.CarregarredeButton = uibutton(app.CarregarumaexistenteTab, 'push');
            app.CarregarredeButton.ButtonPushedFcn = createCallbackFcn(app, @CarregarredeButtonPushed, true);
            app.CarregarredeButton.Text = 'Carregar rede';
            app.RedeEditFieldLabel = uilabel(app.CarregarumaexistenteTab);
            app.RedeEditFieldLabel.Text = 'Rede';
            app.RedeEditField = uieditfield(app.CarregarumaexistenteTab, 'text');
            app.RedeEditField.Editable = 'off';
            app.TabGroup2 = uitabgroup(app.CarregarumaexistenteTab);
            app.DesenharImagemTab = uitab(app.TabGroup2);
            app.DesenharImagemTab.Title = 'Desenhar Imagem';
            app.UIAxesDesenho = uiaxes(app.DesenharImagemTab);
            zlabel(app.UIAxesDesenho, 'Z')
            app.UIAxesDesenho.ButtonDownFcn = createCallbackFcn(app, @UIAxesDesenhoButtonDown, true);
            app.ClearButton = uibutton(app.DesenharImagemTab, 'push');
            app.ClearButton.Text = 'Clear';
            app.saveButton = uibutton(app.DesenharImagemTab, 'push');
            app.saveButton.Text = 'save';
            app.SelecionarimagemTab = uitab(app.TabGroup2);
            app.SelecionarimagemTab.Title = 'Selecionar imagem';
            app.EscolherimagemButton = uibutton(app.SelecionarimagemTab, 'push');
            app.EscolherimagemButton.ButtonPushedFcn = createCallbackFcn(app, @EscolherimagemButtonPushed, true);
            app.EscolherimagemButton.Text = 'Escolher imagem';
            app.UIimage = uiimage(app.SelecionarimagemTab);
            app.EscolherdatasetButton = uibutton(app.CarregarumaexistenteTab, 'push');
            app.EscolherdatasetButton.ButtonPushedFcn = createCallbackFcn(app, @EscolherdatasetButtonPushed2, true);
            app.EscolherdatasetButton.Enable = 'off';
            app.EscolherdatasetButton.Text = 'Carregar dataset';
            app.DatasetEditFieldLabel = uilabel(app.CarregarumaexistenteTab);
            app.DatasetEditFieldLabel.Enable = 'off';
            app.DatasetEditFieldLabel.Text = 'Dataset';
            app.datasetfolderEditField = uieditfield(app.CarregarumaexistenteTab, 'text');
            app.datasetfolderEditField.Editable = 'off';
            app.datasetfolderEditField.Enable = 'off';
            app.ResultadosTextArea_2Label = uilabel(app.CarregarumaexistenteTab);
            app.ResultadosTextArea_2Label.Text = 'Resultados';
            app.ResultadosTextArea_2 = uitextarea(app.CarregarumaexistenteTab);
            app.AplicarButton = uibutton(app.CarregarumaexistenteTab, 'push');
            app.AplicarButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarButtonPushed, true);
            app.AplicarButton.Enable = 'off';
            app.AplicarButton.Text = 'Aplicar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end